classdef app_lab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        tmuestreo            matlab.ui.control.NumericEditField
        tmuestreomsLabel     matlab.ui.control.Label
        Frecmuestreo         matlab.ui.control.NumericEditField
        FrecmuestreoHzLabel  matlab.ui.control.Label
        Ec                   matlab.ui.control.NumericEditField
        EcEditFieldLabel     matlab.ui.control.Label
        Em                   matlab.ui.control.NumericEditField
        EmEditFieldLabel     matlab.ui.control.Label
        Fc                   matlab.ui.control.NumericEditField
        FcEditFieldLabel     matlab.ui.control.Label
        Fm                   matlab.ui.control.NumericEditField
        FmEditFieldLabel     matlab.ui.control.Label
        opcion_espec         matlab.ui.control.DropDown
        DesignarButton       matlab.ui.control.Button
        opcion_Sen           matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DesignarButton
        function DesignarButtonPushed(app, event)
            %Asignacion variables
            Fs=app.Frecmuestreo.Value;   %Frecuencia de muestreo
            Ts=app.tmuestreo.Value;      %Tiempo de muestreo
            fc=app.Fc.Value;
            fm=app.Fm.Value;
            em=app.Em.Value;
            ec=app.Ec.Value;
            t = 0:1e-3:Ts; %Tiempo
            %Señal moduladora
            x = em*sin(2*pi*fm*t);
            plot(app.moduladora,t,x);
            app.moduladora.XLim = [min(t) max(t)-0.8];
            app.moduladora.YLim = [-em-0.1 em+0.1];
             %Señal portadora
            c = ec*sin(2*pi*fc*t);
            plot(app.portadora,t,c);
            app.portadora.XLim = [min(t) max(t)-0.8];
            app.portadora.YLim = [-ec-0.1 ec+0.1];
            %Salida de señal
            V_sal = x+c;
            plot(app.salida,t,V_sal);
            app.salida.XLim = [min(t) max(t)-0.8];
            app.salida.YLim = [-ec-em-0.1 ec+em+0.1];
            %Parametros potencia
            m = 25;
            M = 100;
            pot = 90;
            B = 80;
            %Espectro de frecuencia
%             fli = fc-fm;
%             fls = fc+fm;
%             esp_f = [fli,fc,fls];
%             esp_v = [em/2,em,em/2];
            valor_sen = str2double(app.opcion_espec.Value);
            while valor_sen == 1
                fli = fc-fm;
                fls = fc+fm;
                esp_f = [fli,fc,fls];
                esp_v = [em/2,em,em/2];
                stem(app.espectro,esp_f,esp_v);
            end
           while valor_sen == 2
                fli = fc-fm;
                fls = fc+fm;
                pc = ec^2/2;
                pbli = (m^2*pc)/4;
                pbls = (m^2*pc)/4;
                esp_fp = [fli,fc,fls];
                esp_p =  [pbli,pc,pbls];
                stem(app.espectro,esp_fp,esp_p);
           end
           

            %Envio de datos
            app2_lab1(fm,ec,fc,em,M,m,pot,B);
        end

        % Value changed function: opcion_Sen
        function opcion_SenValueChanged(app, event)
            %value = str2double(app.opcion_Sen.value);


        end

        % Value changed function: opcion_espec
        function opcion_especValueChanged(app, event)
            %valor_sen = str2double(app.opcion_espec.Value);
            %Asignacion variables
%             Fs=app.Frecmuestreo.Value;   %Frecuencia de muestreo
%             Ts=app.tmuestreo.Value;      %Tiempo de muestreo
%             fc=app.Fc.Value;
%             fm=app.Fm.Value;
%             em=app.Em.Value;
%             ec=app.Ec.Value;
%             t = 0:1e-3:Ts; %Tiempo
%             c = ec*sin(2*pi*fc*t);
%             x = em*sin(2*pi*fm*t);
%             if valor_sen == 1
%                 fli = fc-fm;
%                 fls = fc+fm;
%                 esp_f = [fli,fc,fls];
%                 esp_v = [em/2,em,em/2];
%                 stem(app.espectro,esp_f,esp_v);
%             else 
%                 fli = fc-fm;
%                 fls = fc+fm;
%                 pbli = (m^2*pc)/4;
%                 pbls = (m^2*pc)/4;
%                 pc = ec^2/2;
%                 esp_fp = [fli,fc,fls];
%                 esp_p = [pbli,pc,pbls];
%                 plot(app.espectro,esp_fp,esp_p);
%                 app.espectro.XLim = [min(t) max(t)-0.8];
%                 app.espectro.YLim = [-ec-em-0.1 ec+em+0.1];
%             end
%             


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.opcion_Sen = uidropdown(app.UIFigure);
            app.opcion_Sen.Items = {'Señal Sinusoidal [R]', 'Señal Sinusoidal [i]'};
            app.opcion_Sen.ItemsData = {'1', '2', '', ''};
            app.opcion_Sen.ValueChangedFcn = createCallbackFcn(app, @opcion_SenValueChanged, true);
            app.opcion_Sen.Value = '1';
            app.DesignarButton = uibutton(app.UIFigure, 'push');
            app.DesignarButton.ButtonPushedFcn = createCallbackFcn(app, @DesignarButtonPushed, true);
            app.DesignarButton.Text = 'Designar';
            app.opcion_espec = uidropdown(app.UIFigure);
            app.opcion_espec.Items = {'Espectro de frecuencias', 'Espectro de potencia'};
            app.opcion_espec.ItemsData = {'1', '2'};
            app.opcion_espec.ValueChangedFcn = createCallbackFcn(app, @opcion_especValueChanged, true);
            app.opcion_espec.Value = '1';
            app.FmEditFieldLabel = uilabel(app.UIFigure);
            app.FmEditFieldLabel.Text = 'Fm';
            app.Fm = uieditfield(app.UIFigure, 'numeric');
            app.FcEditFieldLabel = uilabel(app.UIFigure);
            app.FcEditFieldLabel.Text = 'Fc';
            app.Fc = uieditfield(app.UIFigure, 'numeric');
            app.EmEditFieldLabel = uilabel(app.UIFigure);
            app.EmEditFieldLabel.Text = 'Em';
            app.Em = uieditfield(app.UIFigure, 'numeric');
            app.EcEditFieldLabel = uilabel(app.UIFigure);
            app.EcEditFieldLabel.Text = 'Ec';
            app.Ec = uieditfield(app.UIFigure, 'numeric');
            app.FrecmuestreoHzLabel = uilabel(app.UIFigure);
            app.FrecmuestreoHzLabel.Text = 'Frec muestreo [Hz]';
            app.Frecmuestreo = uieditfield(app.UIFigure, 'numeric');
            app.Frecmuestreo.Value = 1000;
            app.tmuestreomsLabel = uilabel(app.UIFigure);
            app.tmuestreomsLabel.Text = 't muestreo [ms]';
            app.tmuestreo = uieditfield(app.UIFigure, 'numeric');
            app.tmuestreo.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_lab1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end