classdef app_lab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        tmuestreo            matlab.ui.control.NumericEditField
        tmuestreomsLabel     matlab.ui.control.Label
        Frecmuestreo         matlab.ui.control.NumericEditField
        FrecmuestreoHzLabel  matlab.ui.control.Label
        opcion_espec         matlab.ui.control.DropDown
        DesignarButton       matlab.ui.control.Button
        Ec                   matlab.ui.control.NumericEditField
        EcEditFieldLabel     matlab.ui.control.Label
        Em                   matlab.ui.control.NumericEditField
        EmEditFieldLabel     matlab.ui.control.Label
        Fc                   matlab.ui.control.NumericEditField
        FcEditFieldLabel     matlab.ui.control.Label
        Fm                   matlab.ui.control.NumericEditField
        FmEditFieldLabel     matlab.ui.control.Label
        opcion_Sen           matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Fm
        function FmValueChanged(app, event)
           % valor = app.Fm.Value;
            
        end

        % Button pushed function: DesignarButton
        function DesignarButtonPushed(app, event)
            %Asignacion variables
            Fs=app.Frecmuestreo.Value;   %Frecuencia de muestreo
            Ts=app.tmuestreo.Value;      %Tiempo de muestreo
            fc=app.Fc.Value;
            fm=app.Fm.Value;
            em=app.Em.Value;
            ec=app.Ec.Value;
            t = 0:1e-3:Ts; %Tiempo
            %Señal moduladora
            x = em*sin(2*pi*fm*t);
            plot(app.moduladora,t,x);
            app.moduladora.XLim = [min(t) max(t)-0.8];
            app.moduladora.YLim = [-em-0.1 em+0.1];
             %Señal portadora
            c = ec*sin(2*pi*fc*t);
            plot(app.portadora,t,c);
            app.portadora.XLim = [min(t) max(t)-0.8];
            app.portadora.YLim = [-ec-0.1 ec+0.1];
            %Salida de señal
            V_sal = x+c;
            plot(app.salida,t,V_sal);
            app.salida.XLim = [min(t) max(t)-0.8];
            app.salida.YLim = [-ec-em-0.1 ec+em+0.1];
            %Espectro de frecuencia

%             fli = fc-fm;
%             fls = fc+fm;
%             espectro = [fli,fc,fls];
%             stem(app.espectro_frec,t,espectro)
            %Espectro de potencias

            %Parametros potencia
            m = 25;
            M = 100;
            pot = 90;
            B = 80;
            %Envio de datos
            app2_lab1(fm,ec,fc,em,M,m,pot,B);
%             cla(app.moduladora,"reset");
%             cla(app.portadora,"reset");
%             cla(app.salida,"reset");
%             cla(app.espectro_frec,"reset");
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: opcion_Sen
        function opcion_SenValueChanged(app, event)
            %value = app.opcion_Sen.Value;
            
            %if value == "Señal Sinusoidal [R]"


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.opcion_Sen = uidropdown(app.UIFigure);
            app.opcion_Sen.Items = {'Señal Sinusoidal [R]', 'Señal Sinusoidal [i]'};
            app.opcion_Sen.ValueChangedFcn = createCallbackFcn(app, @opcion_SenValueChanged, true);
            app.opcion_Sen.Value = 'Señal Sinusoidal [R]';
            app.FmEditFieldLabel = uilabel(app.UIFigure);
            app.FmEditFieldLabel.Text = 'Fm';
            app.Fm = uieditfield(app.UIFigure, 'numeric');
            app.Fm.ValueChangedFcn = createCallbackFcn(app, @FmValueChanged, true);
            app.FcEditFieldLabel = uilabel(app.UIFigure);
            app.FcEditFieldLabel.Text = 'Fc';
            app.Fc = uieditfield(app.UIFigure, 'numeric');
            app.EmEditFieldLabel = uilabel(app.UIFigure);
            app.EmEditFieldLabel.Text = 'Em';
            app.Em = uieditfield(app.UIFigure, 'numeric');
            app.EcEditFieldLabel = uilabel(app.UIFigure);
            app.EcEditFieldLabel.Text = 'Ec';
            app.Ec = uieditfield(app.UIFigure, 'numeric');
            app.DesignarButton = uibutton(app.UIFigure, 'push');
            app.DesignarButton.ButtonPushedFcn = createCallbackFcn(app, @DesignarButtonPushed, true);
            app.DesignarButton.Text = 'Designar';
            app.opcion_espec = uidropdown(app.UIFigure);
            app.opcion_espec.Items = {'Espectro de frecuencias', 'Espectro de potencia'};
            app.opcion_espec.Value = 'Espectro de potencia';
            app.FrecmuestreoHzLabel = uilabel(app.UIFigure);
            app.FrecmuestreoHzLabel.Text = 'Frec muestreo [Hz]';
            app.Frecmuestreo = uieditfield(app.UIFigure, 'numeric');
            app.Frecmuestreo.Value = 1000;
            app.tmuestreomsLabel = uilabel(app.UIFigure);
            app.tmuestreomsLabel.Text = 't muestreo [ms]';
            app.tmuestreo = uieditfield(app.UIFigure, 'numeric');
            app.tmuestreo.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_lab1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end