classdef app_lab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        tmuestreo         matlab.ui.control.NumericEditField
        tmuestreomsLabel  matlab.ui.control.Label
        Ec                matlab.ui.control.NumericEditField
        EcEditFieldLabel  matlab.ui.control.Label
        Em                matlab.ui.control.NumericEditField
        EmEditFieldLabel  matlab.ui.control.Label
        Fc                matlab.ui.control.NumericEditField
        FcEditFieldLabel  matlab.ui.control.Label
        Fm                matlab.ui.control.NumericEditField
        FmEditFieldLabel  matlab.ui.control.Label
        opcion_espec      matlab.ui.control.DropDown
        DesignarButton    matlab.ui.control.Button
        opcion_Sen        matlab.ui.control.DropDown
        espectro          matlab.ui.control.UIAxes
        salida            matlab.ui.control.UIAxes
        portadora         matlab.ui.control.UIAxes
        moduladora        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: DesignarButton
        function DesignarButtonPushed(app, event)
            %Asignacion variables
               
            Ts=app.tmuestreo.Value;      %Tiempo de muestreo
            fc=app.Fc.Value;
            fm=app.Fm.Value;
            fm2=2000;
            em=app.Em.Value;
            ec=app.Ec.Value;
            t = 0:1e-3:Ts; %Tiempo
            %Señal moduladora
            x = em*sin(2*pi*fm*t);
            plot(app.moduladora,t,x,"Color","g","LineWidth",1);
            app.moduladora.Title.String = 'Señal Moduladora';
            app.moduladora.XLim = [min(t) max(t)-0.8];
            app.moduladora.YLim = [-em-0.1 em+0.1];
             %Señal portadora
            c = ec*sin(2*pi*fc*t);
            plot(app.portadora,t,c,"Color","c","LineWidth",1);
            app.portadora.Title.String = 'Señal Portadora';
            app.portadora.XLim = [min(t) max(t)-0.8];
            app.portadora.YLim = [-ec-0.1 ec+0.1];
            %Salida de señal
            V_sal = x+c;
            plot(app.salida,t,V_sal,"LineWidth",1);
            app.salida.Title.String = 'Salida AM';
            app.salida.XLim = [min(t) max(t)-0.8];
            app.salida.YLim = [-ec-em-0.1 ec+em+0.1];
            %Parametros potencia
            m = em/ec;
            M = m*100;
            pot = m/100;
            B = m*1000;
            %Espectro
            valor_sen = str2double(app.opcion_espec.Value);
            if valor_sen == 1
                fli = fc-fm;
                fls = fc+fm;
                esp_f = [fli,fc,fls];
                esp_v = [em/2,ec,em/2];
                stem(app.espectro,esp_f,esp_v,"filled","Color","r");
                grid minor;
                app.espectro.Title.String = 'Espectro de frecuencias';
            else 
                fli = fc-fm;
                fls = fc+fm;
                pc = ec^2/2;
                pbli = (m^2*pc)/4;
                pbls = (m^2*pc)/4;
                esp_fp = [fli,fc,fls];
                esp_p =  [pbli,pc,pbls];
                stem(app.espectro,esp_fp,esp_p,"filled","Color","m");
                grid minor;
                app.espectro.Title.String = 'Espectro de Potencia';
            end
            value_complex = str2double(app.opcion_Sen.Value);
            if value_complex == 1
                %Señal moduladora
                x = em*sin(2*pi*fm*t);
                plot(app.moduladora,t,x,"Color","g","LineWidth",1);
                app.moduladora.Title.String = 'Señal Moduladora';
                app.moduladora.XLim = [min(t) max(t)-0.8];
                app.moduladora.YLim = [-em-0.1 em+0.1];
                 %Señal portadora
                c = ec*sin(2*pi*fc*t);
                plot(app.portadora,t,c,"Color","c","LineWidth",1);
                app.portadora.Title.String = 'Señal Portadora';
                app.portadora.XLim = [min(t) max(t)-0.8];
                app.portadora.YLim = [-ec-0.1 ec+0.1];
                %Salida de señal
                V_sal = x+c;
                plot(app.salida,t,V_sal,"LineWidth",1);
                app.salida.Title.String = 'Salida AM';
                app.salida.XLim = [min(t) max(t)-0.8];
                app.salida.YLim = [-ec-em-0.1 ec+em+0.1];
                    %Parametros potencia
                    m = em/ec;
                    M = m*100;
                    pot = m/100;
                    B = m*1000;
                    %Espectro
                    valor_sen = str2double(app.opcion_espec.Value);
                    cla(app.espectro,"reset");
                    if valor_sen == 1
                        fli = fc-fm;
                        fls = fc+fm;
                        esp_f = [fli,fc,fls];
                        esp_v = [em/2,ec,em/2];
                        stem(app.espectro,esp_f,esp_v,"filled","Color","r");
                        grid minor;
                        app.espectro.Title.String = 'Espectro de frecuencias';
                    else 
                        fli = fc-fm;
                        fls = fc+fm;
                        pc = ec^2/2;
                        pbli = (m^2*pc)/4;
                        pbls = (m^2*pc)/4;
                        esp_fp = [fli,fc,fls];
                        esp_p =  [pbli,pc,pbls];
                        stem(app.espectro,esp_fp,esp_p,"filled","Color","m");
                        grid minor;
                        app.espectro.Title.String = 'Espectro de Potencia';
                   end
           
            else 

                %Señal moduladora
                x = sin(2*pi*fm*t);
                plot(app.moduladora,t,x,"Color","g","LineWidth",1);
                app.moduladora.XLim = [min(t) max(t)-0.8];
    
                 %Señal inferior
                inf = 1/2*cos(2*pi*(fc-fm)*t)-1/2*cos(2*pi*(fc+fm)*t);
                plot(app.portadora,t,inf,"Color","c","LineWidth",1);
                app.portadora.Title.String = 'Señal inferior';
                app.portadora.XLim = [min(t) max(t)-0.8];
    
                %Señal superior
                sup = 1/2*cos(2*pi*(fc-fm2)*t)-1/2*cos(2*pi*(fc+fm2)*t);
                plot(app.salida,t,sup,"LineWidth",1);
                app.salida.Title.String = 'Señal superior';
                app.salida.XLim = [min(t) max(t)-0.8];

                %señal total
                supinf = x + inf + sup;
                plot(app.espectro,t,supinf);
                app.espectro.Title.String = 'Salida AM';
                grid minor;
                app.espectro.XLim = [min(t) max(t)-0.8];
            end
           

            %Envio de datos
            app2_lab1(fm,ec,fc,em,M,m,pot,B);
        end

        % Value changed function: opcion_espec
        function opcion_especValueChanged(app, event)
  
            %Asignacion variables
            fc=app.Fc.Value;
            fm=app.Fm.Value;
            em=app.Em.Value;
            ec=app.Ec.Value;
            %Parametros potencia
            m = em/ec;
            valor_sen = str2double(app.opcion_espec.Value);
            if valor_sen == 1
                fli = fc-fm;
                fls = fc+fm;
                esp_f = [fli,fc,fls];
                esp_v = [em/2,ec,em/2];
                stem(app.espectro,esp_f,esp_v,"filled","Color","r");
                grid minor;
                app.espectro.Title.String = 'Espectro de frecuencias';
            else 
                fli = fc-fm;
                fls = fc+fm;
                pc = ec^2/2;
                pbli = (m^2*pc)/4;
                pbls = (m^2*pc)/4;
                esp_fp = [fli,fc,fls];
                esp_p =  [pbli,pc,pbls];
                stem(app.espectro,esp_fp,esp_p,"filled","Color","m");
                grid minor;
                app.espectro.Title.String = 'Espectro de Potencia';
           end
            
        end

        % Value changed function: opcion_Sen
        function opcion_SenValueChanged(app, event)
            value_complex = str2double(app.opcion_Sen.Value);
            %Asignacion variables
               
            Ts=app.tmuestreo.Value;      %Tiempo de muestreo
            fc=app.Fc.Value;
            fm=app.Fm.Value;
            fm2=2000;
            em = app.Em.Value;
            ec=app.Ec.Value;
            t = 0:1e-3:Ts; %Tiempo
            if value_complex == 1
                %Señal moduladora
                x = em*sin(2*pi*fm*t);
                plot(app.moduladora,t,x,"Color","g","LineWidth",1);
                app.moduladora.Title.String = 'Señal Moduladora';
                app.moduladora.XLim = [min(t) max(t)-0.8];
                app.moduladora.YLim = [-em-0.1 em+0.1];
                 %Señal portadora
                c = ec*sin(2*pi*fc*t);
                plot(app.portadora,t,c,"Color","c","LineWidth",1);
                app.portadora.Title.String = 'Señal portadora';
                app.portadora.XLim = [min(t) max(t)-0.8];
                app.portadora.YLim = [-ec-0.1 ec+0.1];
                %Salida de señal
                V_sal = x+c;
                plot(app.salida,t,V_sal,"LineWidth",1);
                app.salida.Title.String = 'Salida AM';
                app.salida.XLim = [min(t) max(t)-0.8];
                app.salida.YLim = [-ec-em-0.1 ec+em+0.1];
                    %Parametros potencia
                    m = em/ec;
                    M = m*100;
                    pot = m/100;
                    B = m*1000;
                    %Espectro
                    valor_sen = str2double(app.opcion_espec.Value);
                    cla(app.espectro,"reset");
                    if valor_sen == 1
                        fli = fc-fm;
                        fls = fc+fm;
                        esp_f = [fli,fc,fls];
                        esp_v = [em/2,ec,em/2];
                        stem(app.espectro,esp_f,esp_v,"filled","Color","r");
                        grid minor;
                        app.espectro.Title.String = 'Espectro de frecuencias';
                    else 
                        fli = fc-fm;
                        fls = fc+fm;
                        pc = ec^2/2;
                        pbli = (m^2*pc)/4;
                        pbls = (m^2*pc)/4;
                        esp_fp = [fli,fc,fls];
                        esp_p =  [pbli,pc,pbls];
                        stem(app.espectro,esp_fp,esp_p,"filled","Color","m");
                        grid minor;
                        app.espectro.Title.String = 'Espectro de Potencia';
                   end
           
            else 

                %Señal moduladora
                x = sin(2*pi*fm*t);
                plot(app.moduladora,t,x,"Color","g","LineWidth",1);
                app.moduladora.XLim = [min(t) max(t)-0.8];
    
                 %Señal inferior
                inf = 1/2*cos(2*pi*(fc-fm)*t)-1/2*cos(2*pi*(fc+fm)*t);
                plot(app.portadora,t,inf,"Color","c","LineWidth",1);
                app.portadora.Title.String = 'Señal inferior';
                app.portadora.XLim = [min(t) max(t)-0.8];
    
                %Señal superior
                sup = 1/2*cos(2*pi*(fc-fm2)*t)-1/2*cos(2*pi*(fc+fm2)*t);
                plot(app.salida,t,sup,"LineWidth",1);
                app.salida.Title.String = 'Señal superior';
                app.salida.XLim = [min(t) max(t)-0.8];

                %señal total
                supinf = x + inf + sup;
                plot(app.espectro,t,supinf);
                grid minor;
                app.espectro.Title.String = 'Salida AM';
                app.espectro.XLim = [min(t) max(t)-0.8];
                
            end
            
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.moduladora = uiaxes(app.UIFigure);
            title(app.moduladora, 'Señal Moduladora')
            xlabel(app.moduladora, 't [ms]')
            ylabel(app.moduladora, 'V_m [V]')
            zlabel(app.moduladora, 'Z')
            app.portadora = uiaxes(app.UIFigure);
            title(app.portadora, 'Señal Portadora')
            xlabel(app.portadora, 't [ms]')
            ylabel(app.portadora, 'V_c [V]')
            zlabel(app.portadora, 'Z')
            app.salida = uiaxes(app.UIFigure);
            title(app.salida, 'Señal AM')
            xlabel(app.salida, 't [ms]')
            ylabel(app.salida, 'v_out [V]')
            zlabel(app.salida, 'Z')
            app.espectro = uiaxes(app.UIFigure);
            title(app.espectro, 'Espectro')
            xlabel(app.espectro, 'Frec [Hz]')
            ylabel(app.espectro, 'V')
            zlabel(app.espectro, 'Z')
            app.opcion_Sen = uidropdown(app.UIFigure);
            app.opcion_Sen.Items = {'Señal Sinusoidal [R]', 'Señal Sinusoidal [i]'};
            app.opcion_Sen.ItemsData = {'1', '2', '', ''};
            app.opcion_Sen.ValueChangedFcn = createCallbackFcn(app, @opcion_SenValueChanged, true);
            app.opcion_Sen.Value = '1';
            app.DesignarButton = uibutton(app.UIFigure, 'push');
            app.DesignarButton.ButtonPushedFcn = createCallbackFcn(app, @DesignarButtonPushed, true);
            app.DesignarButton.Text = 'Designar';
            app.opcion_espec = uidropdown(app.UIFigure);
            app.opcion_espec.Items = {'Espectro de frecuencias', 'Espectro de potencia'};
            app.opcion_espec.ItemsData = {'1', '2'};
            app.opcion_espec.ValueChangedFcn = createCallbackFcn(app, @opcion_especValueChanged, true);
            app.opcion_espec.Value = '1';
            app.FmEditFieldLabel = uilabel(app.UIFigure);
            app.FmEditFieldLabel.Text = 'Fm';
            app.Fm = uieditfield(app.UIFigure, 'numeric');
            app.FcEditFieldLabel = uilabel(app.UIFigure);
            app.FcEditFieldLabel.Text = 'Fc';
            app.Fc = uieditfield(app.UIFigure, 'numeric');
            app.EmEditFieldLabel = uilabel(app.UIFigure);
            app.EmEditFieldLabel.Text = 'Em';
            app.Em = uieditfield(app.UIFigure, 'numeric');
            app.EcEditFieldLabel = uilabel(app.UIFigure);
            app.EcEditFieldLabel.Text = 'Ec';
            app.Ec = uieditfield(app.UIFigure, 'numeric');
            app.tmuestreomsLabel = uilabel(app.UIFigure);
            app.tmuestreomsLabel.Text = 'tiempo de muestreo [ms]';
            app.tmuestreo = uieditfield(app.UIFigure, 'numeric');
            app.tmuestreo.Value = 1;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_lab1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end